classdef AlgorithmComparisonApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        runSimulationButton           matlab.ui.control.Button
        randomSeedEditField           matlab.ui.control.NumericEditField
        RandomSeedEditFieldLabel      matlab.ui.control.Label
        datasetSizeEditField          matlab.ui.control.NumericEditField
        DataSetSizeEditFieldLabel     matlab.ui.control.Label
        algorithimDropdown            matlab.ui.control.DropDown
        SelectAlgorithmDropDownLabel  matlab.ui.control.Label
        simulationAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    lastRun1Time          % Time vector for the most recent run
    lastRun1Comparisons  % Number of comparisons for the most recent run
    lastRun2Time          % Time vector for the second most recent run
    lastRun2Comparisons  % Number of comparisons for the second most recent run
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runSimulationButton
        function runSimulationButtonPushed(app, event)
    try
        % Retrieve and validate inputs
        algorithm = app.algorithmDropdown.Value;
        datasetSize = app.datasetSizeEditField.Value;
        seed = app.randomSeedEditField.Value;
        
        % Input Validation
        if datasetSize < 1 || ~isscalar(datasetSize) || floor(datasetSize) ~= datasetSize
            uialert(app.UIFigure, 'Dataset size must be a positive integer.', 'Input Error');
            return;
        end
        
        if ~isscalar(seed) || floor(seed) ~= seed
            uialert(app.UIFigure, 'Random seed must be an integer.', 'Input Error');
            return;
        end
        
        % Set the random seed for repeatable simulations
        rng(seed);
        
        % Generate a random dataset
        dataset = randi(10000, 1, datasetSize); % Array of random integers between 1 and 10,000
        
        % Initialize variables for performance metrics
        comparisons = 0;
        runtime = 0;
        
        % Run the selected algorithm and measure performance
        tic; % Start timer
        [sortedData, comparisons] = app.selectSortAlgorithm(algorithm, dataset);
        runtime = toc; % End timer
        
        % Manage previous runs
        if ~isempty(app.lastRun1Time)
            % Shift current run to previous run
            app.lastRun2Time = app.lastRun1Time;
            app.lastRun2Comparisons = app.lastRun1Comparisons;
        end
        % Update the most recent run
        app.lastRun1Time = datasetSize;
        app.lastRun1Comparisons = comparisons;
        
        % Plotting
        cla(app.simulationAxes); % Clear current axes
        hold(app.simulationAxes, 'on'); % Hold on to plot multiple lines
        
        % Plot previous run if exists
        if ~isempty(app.lastRun2Time)
            plot(app.simulationAxes, app.lastRun2Time, app.lastRun2Comparisons, 'r--', 'LineWidth', 1.5);
        end
        
        % Plot current run
        plot(app.simulationAxes, app.lastRun1Time, app.lastRun1Comparisons, 'b-', 'LineWidth', 2);
        
        hold(app.simulationAxes, 'off'); % Release hold
        
        % Enhance the plot
        grid(app.simulationAxes, 'on');
        xlabel(app.simulationAxes, 'Dataset Size');
        ylabel(app.simulationAxes, 'Number of Comparisons');
        title(app.simulationAxes, 'Algorithm Performance Comparison');
        legend(app.simulationAxes, {'Previous Run', 'Current Run'}, 'Location', 'best');
        
    catch ME
        % Handle unexpected errors
        uialert(app.UIFigure, ['An error occurred: ', ME.message], 'Simulation Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.simulationAxes = uiaxes(app.UIFigure);
            title(app.simulationAxes, 'Performance Comparison')
            xlabel(app.simulationAxes, 'Data Set Size')
            ylabel(app.simulationAxes, 'Time (s) / Comparisons')
            zlabel(app.simulationAxes, 'Z')
            app.SelectAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.SelectAlgorithmDropDownLabel.Text = 'Select Algorithm';
            app.algorithimDropdown = uidropdown(app.UIFigure);
            app.algorithimDropdown.Items = {'Bubble Sort', 'Quick Sort'};
            app.algorithimDropdown.Value = 'Bubble Sort';
            app.DataSetSizeEditFieldLabel = uilabel(app.UIFigure);
            app.DataSetSizeEditFieldLabel.Text = 'Data Set Size:';
            app.datasetSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.datasetSizeEditField.Limits = [1 10000];
            app.datasetSizeEditField.Value = 1;
            app.RandomSeedEditFieldLabel = uilabel(app.UIFigure);
            app.RandomSeedEditFieldLabel.Text = 'Random Seed:';
            app.randomSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.randomSeedEditField.Value = 1;
            app.runSimulationButton = uibutton(app.UIFigure, 'push');
            app.runSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @runSimulationButtonPushed, true);
            app.runSimulationButton.Text = 'Run Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AlgorithmComparisonApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end