classdef PIDLineFollowingRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        runSimulationButton       matlab.ui.control.Button
        RandomSeedEditField       matlab.ui.control.NumericEditField
        RandomSeedEditFieldLabel  matlab.ui.control.Label
        SetPointEditField         matlab.ui.control.NumericEditField
        SetPointEditFieldLabel    matlab.ui.control.Label
        kDEditField               matlab.ui.control.NumericEditField
        kDEditFieldLabel          matlab.ui.control.Label
        kIEditField               matlab.ui.control.NumericEditField
        kIEditFieldLabel          matlab.ui.control.Label
        kPEditField               matlab.ui.control.NumericEditField
        kPEditFieldLabel          matlab.ui.control.Label
        simulationAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    lastRun1Time      % Stores time vector for the most recent run
    lastRun1Sensor    % Stores sensor readings for the most recent run
    lastRun1Motor     % Stores motor speeds for the most recent run
    lastRun2Time      % Stores time vector for the second most recent run
    lastRun2Sensor    % Stores sensor readings for the second most recent run
    lastRun2Motor     % Stores motor speeds for the second most recent run
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runSimulationButton
        function runSimulationButtonPushed(app, event)
    try
        % Retrieve and validate inputs
        kP = app.kPEditField.Value;
        kI = app.kIEditField.Value;
        kD = app.kDEditField.Value;
        setpoint = app.SetPointEditField.Value; % Corrected
        seed = app.RandomSeedEditField.Value;   % Corrected
        
        % Validate inputs (optional but recommended)
        if kP < 0 || kI < 0 || kD < 0
            uialert(app.UIFigure, 'PID gains must be non-negative.', 'Input Error');
            return;
        end
        
        if setpoint <= 0
            uialert(app.UIFigure, 'Setpoint must be a positive value.', 'Input Error');
            return;
        end
        
        if ~isscalar(seed) || floor(seed) ~= seed
            uialert(app.UIFigure, 'Random seed must be an integer.', 'Input Error');
            return;
        end
        
        % Set the random seed for repeatable simulations
        rng(seed);
        
        % Run the simulation using the provided function
        % Assuming simulateLineFollowerPololu returns [time, sensorReadings, motorSpeeds]
        [time, sensorReadings, motorSpeeds] = simulateLineFollowerPololu(kP, kI, kD, setpoint);
        
        % Manage previous runs
        if ~isempty(app.lastRun1Time)
            % Shift current run to previous run
            app.lastRun2Time = app.lastRun1Time;
            app.lastRun2Sensor = app.lastRun1Sensor;
            app.lastRun2Motor = app.lastRun1Motor;
        end
        % Update the most recent run
        app.lastRun1Time = time;
        app.lastRun1Sensor = sensorReadings;
        app.lastRun1Motor = motorSpeeds;
        
        % Plotting
        cla(app.simulationAxes); % Clear current axes
        hold(app.simulationAxes, 'on'); % Hold on to plot multiple lines
        
        % Plot previous run if exists
        if ~isempty(app.lastRun2Time)
            plot(app.simulationAxes, app.lastRun2Time, app.lastRun2Sensor, 'r--', 'LineWidth', 1.5);
            plot(app.simulationAxes, app.lastRun2Time, app.lastRun2Motor, 'g--', 'LineWidth', 1.5);
        end
        
        % Plot current run
        plot(app.simulationAxes, app.lastRun1Time, app.lastRun1Sensor, 'b-', 'LineWidth', 2);
        plot(app.simulationAxes, app.lastRun1Time, app.lastRun1Motor, 'm-', 'LineWidth', 2);
        
        hold(app.simulationAxes, 'off'); % Release hold
        
        % Enhance the plot
        grid(app.simulationAxes, 'on');
        xlabel(app.simulationAxes, 'Time (s)');
        ylabel(app.simulationAxes, 'Value');
        title(app.simulationAxes, 'Sensor Readings and Motor Speeds Over Time');
        legend(app.simulationAxes, {'Previous Sensor', 'Previous Motor', 'Current Sensor', 'Current Motor'}, 'Location', 'best');
        
    catch ME
        % Handle unexpected errors
        uialert(app.UIFigure, ['An error occurred: ', ME.message], 'Simulation Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.simulationAxes = uiaxes(app.UIFigure);
            title(app.simulationAxes, 'Sensor Readings and Motor Speeds Over Time')
            xlabel(app.simulationAxes, 'Time (s)')
            ylabel(app.simulationAxes, 'Value')
            zlabel(app.simulationAxes, 'Z')
            app.kPEditFieldLabel = uilabel(app.UIFigure);
            app.kPEditFieldLabel.Text = 'kP:';
            app.kPEditField = uieditfield(app.UIFigure, 'numeric');
            app.kPEditField.Value = 1;
            app.kIEditFieldLabel = uilabel(app.UIFigure);
            app.kIEditFieldLabel.Text = 'kI:';
            app.kIEditField = uieditfield(app.UIFigure, 'numeric');
            app.kIEditField.Value = 0.1;
            app.kDEditFieldLabel = uilabel(app.UIFigure);
            app.kDEditFieldLabel.Text = 'kD:';
            app.kDEditField = uieditfield(app.UIFigure, 'numeric');
            app.kDEditField.Value = 0.05;
            app.SetPointEditFieldLabel = uilabel(app.UIFigure);
            app.SetPointEditFieldLabel.Text = 'Set Point:';
            app.SetPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetPointEditField.Value = 10;
            app.RandomSeedEditFieldLabel = uilabel(app.UIFigure);
            app.RandomSeedEditFieldLabel.Text = 'Random Seed:';
            app.RandomSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.runSimulationButton = uibutton(app.UIFigure, 'push');
            app.runSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @runSimulationButtonPushed, true);
            app.runSimulationButton.Text = 'Run Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIDLineFollowingRobot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end