classdef PIDSpringDampingVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunSimulationButton             matlab.ui.control.Button
        DesiredSetpointmEditField       matlab.ui.control.NumericEditField
        DesiredSetpointmEditFieldLabel  matlab.ui.control.Label
        kDEditField                     matlab.ui.control.NumericEditField
        kDEditFieldLabel                matlab.ui.control.Label
        kIEditField                     matlab.ui.control.NumericEditField
        kIEditFieldLabel                matlab.ui.control.Label
        kPEditField                     matlab.ui.control.NumericEditField
        kPEditFieldLabel                matlab.ui.control.Label
        SpringConstantEditField         matlab.ui.control.NumericEditField
        SpringConstantNmEditFieldLabel  matlab.ui.control.Label
        DampingCoefficientEditField     matlab.ui.control.NumericEditField
        DampingCoefficientLabel         matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        PlotAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    lastRun1Time      % Stores time vector for the most recent run
    lastRun1Position  % Stores position vector for the most recent run
    lastRun2Time      % Stores time vector for the second most recent run
    lastRun2Position  % Stores position vector for the second most recent run

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
    try
        % Retrieve and validate inputs
        m = app.MassEditField.Value;
        c = app.DampingCoefficientEditField.Value;
        k = app.SpringConstantEditField.Value;
        kP = app.kPEditField.Value;
        kI = app.kIEditField.Value;
        kD = app.kDEditField.Value;
        setpoint = app.DesiredSetpointmEditField.Value;  % Corrected component name

        % Validate mass
        if m <= 0
            app.MassEditField.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Mass must be a positive value.', 'Input Error');
            return;
        else
            app.MassEditField.BackgroundColor = [1 1 1]; % Reset to white
        end

        % Validate damping coefficient
        if c < 0
            app.DampingCoefficientEditField.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Damping coefficient cannot be negative.', 'Input Error');
            return;
        else
            app.DampingCoefficientEditField.BackgroundColor = [1 1 1]; % Reset to white
        end

        % Validate spring constant
        if k <= 0
            app.SpringConstantEditField.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Spring constant must be a positive value.', 'Input Error');
            return;
        else
            app.SpringConstantEditField.BackgroundColor = [1 1 1]; % Reset to white
        end

        % Validate PID gains
        if any([kP, kI, kD] < 0)
            if kP < 0
                app.kPEditField.BackgroundColor = [1 0.8 0.8];
            else
                app.kPEditField.BackgroundColor = [1 1 1];
            end
            if kI < 0
                app.kIEditField.BackgroundColor = [1 0.8 0.8];
            else
                app.kIEditField.BackgroundColor = [1 1 1];
            end
            if kD < 0
                app.kDEditField.BackgroundColor = [1 0.8 0.8];
            else
                app.kDEditField.BackgroundColor = [1 1 1];
            end
            uialert(app.UIFigure, 'PID gains must be non-negative.', 'Input Error');
            return;
        else
            app.kPEditField.BackgroundColor = [1 1 1];
            app.kIEditField.BackgroundColor = [1 1 1];
            app.kDEditField.BackgroundColor = [1 1 1];
        end

        % Validate setpoint
        if ~isnumeric(setpoint)
            app.DesiredSetpointmEditField.BackgroundColor = [1 0.8 0.8]; % Corrected component name
            uialert(app.UIFigure, 'Setpoint must be a numeric value.', 'Input Error');
            return;
        else
            app.DesiredSetpointmEditField.BackgroundColor = [1 1 1];
        end

        % Run the simulation using the simulatePIDControl function
        [time, position] = simulatePIDControl(m, c, k, kP, kI, kD, setpoint);

        % Manage last two runs
        if isprop(app, 'lastRun1Time') && isprop(app, 'lastRun1Position') && ...
           isprop(app, 'lastRun2Time') && isprop(app, 'lastRun2Position')
            % Shift previous runs
            app.lastRun2Time = app.lastRun1Time;
            app.lastRun2Position = app.lastRun1Position;
            app.lastRun1Time = time;
            app.lastRun1Position = position;
        else
            % Initialize runs
            app.lastRun1Time = time;
            app.lastRun1Position = position;
            app.lastRun2Time = [];
            app.lastRun2Position = [];
        end

        % Plot the results
        cla(app.PlotAxes); % Clear current axes

        hold(app.PlotAxes, 'on');

        % Plot the previous run if it exists
        if ~isempty(app.lastRun2Time) && ~isempty(app.lastRun2Position)
            plot(app.PlotAxes, app.lastRun2Time, app.lastRun2Position, 'r--', 'LineWidth', 1.5);
        end

        % Plot the current run
        plot(app.PlotAxes, app.lastRun1Time, app.lastRun1Position, 'b-', 'LineWidth', 2);

        hold(app.PlotAxes, 'off');

        % Enhance the plot
        grid(app.PlotAxes, 'on');
        xlabel(app.PlotAxes, 'Time (s)');       % Corrected labels
        ylabel(app.PlotAxes, 'Position (m)');
        title(app.PlotAxes, 'PID Control of Spring-Damping System');
        legend(app.PlotAxes, {'Previous Run', 'Current Run'}, 'Location', 'best');

    catch ME
        % Handle and display errors using a dialog box
        uialert(app.UIFigure, ['Error: ', ME.message], 'Simulation Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotAxes = uiaxes(app.UIFigure);
            title(app.PlotAxes, 'System Position over Time')
            xlabel(app.PlotAxes, 'Position (m)')
            ylabel(app.PlotAxes, 'Time (s)')
            zlabel(app.PlotAxes, 'Z')
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.Value = 1;
            app.DampingCoefficientLabel = uilabel(app.UIFigure);
            app.DampingCoefficientLabel.Text = 'Damping Coefficient(N*S/m):';
            app.DampingCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.DampingCoefficientEditField.Value = 0.5;
            app.SpringConstantNmEditFieldLabel = uilabel(app.UIFigure);
            app.SpringConstantNmEditFieldLabel.Text = 'Spring Constant (N/m):';
            app.SpringConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringConstantEditField.Value = 20;
            app.kPEditFieldLabel = uilabel(app.UIFigure);
            app.kPEditFieldLabel.Text = 'kP:';
            app.kPEditField = uieditfield(app.UIFigure, 'numeric');
            app.kPEditField.Value = 100;
            app.kIEditFieldLabel = uilabel(app.UIFigure);
            app.kIEditFieldLabel.Text = 'kI:';
            app.kIEditField = uieditfield(app.UIFigure, 'numeric');
            app.kIEditField.Value = 1;
            app.kDEditFieldLabel = uilabel(app.UIFigure);
            app.kDEditFieldLabel.Text = 'kD:';
            app.kDEditField = uieditfield(app.UIFigure, 'numeric');
            app.kDEditField.Value = 20;
            app.DesiredSetpointmEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredSetpointmEditFieldLabel.Text = 'Desired Setpoint (m):';
            app.DesiredSetpointmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredSetpointmEditField.Value = 0.5;
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIDSpringDampingVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end