classdef FourierSeriesVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlotButton                    matlab.ui.control.Button
        PlotEndEditField              matlab.ui.control.NumericEditField
        PlotRangeEndEditFieldLabel    matlab.ui.control.Label
        PlotStartEditField            matlab.ui.control.NumericEditField
        PlotRangeStartEditFieldLabel  matlab.ui.control.Label
        FourierTermsEditField         matlab.ui.control.NumericEditField
        NumberofFourierSeriesTermsEditFieldLabel  matlab.ui.control.Label
        SymbolicFunctionTextArea      matlab.ui.control.TextArea
        EnterPeriodicSymbolicFunctionTextAreaLabel  matlab.ui.control.Label
        PlotAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
    try
        % Retrieve the symbolic function from the Text Area
        funcStr = strtrim(app.SymbolicFunctionTextArea.Value);
        
        % Validate that the function is not empty
        if isempty(funcStr)
            app.SymbolicFunctionTextArea.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Please enter a periodic symbolic function.', 'Input Error');
            return;
        else
            app.SymbolicFunctionTextArea.BackgroundColor = [1 1 1]; % Reset to white
        end

        % Convert the string to a symbolic expression using str2sym
        try
            func = str2sym(funcStr);
        catch
            app.SymbolicFunctionTextArea.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Invalid symbolic function. Please check your input.', 'Function Error');
            return;
        end

        % Check if the function is periodic
        periodicFunctions = {'sin', 'cos', 'square', 'sawtooth', 'triangular'};
        funcStrLower = lower(char(func));
        isPeriodic = false;
        for i = 1:length(periodicFunctions)
            if contains(funcStrLower, [periodicFunctions{i}, '('])
                isPeriodic = true;
                break;
            end
        end

        if ~isPeriodic
            uialert(app.UIFigure, 'The function entered is not recognized as a standard periodic function.', 'Periodicity Error');
            return;
        end

        % Retrieve the number of Fourier series terms
        numTerms = app.FourierTermsEditField.Value;

        % Validate the number of terms
        if isempty(numTerms) || numTerms <= 0 || floor(numTerms) ~= numTerms
            app.FourierTermsEditField.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Please enter a valid positive integer for the number of terms.', 'Input Error');
            return;
        else
            app.FourierTermsEditField.BackgroundColor = [1 1 1]; % Reset to white
        end

        % Retrieve and validate the plot range
        xStart = app.PlotStartEditField.Value;
        xEnd = app.PlotEndEditField.Value;

        if xStart >= xEnd
            app.PlotStartEditField.BackgroundColor = [1 0.8 0.8]; % Light red
            app.PlotEndEditField.BackgroundColor = [1 0.8 0.8];   % Light red
            uialert(app.UIFigure, 'Plot Range Start must be less than Plot Range End.', 'Range Error');
            return;
        else
            app.PlotStartEditField.BackgroundColor = [1 1 1]; % Reset to white
            app.PlotEndEditField.BackgroundColor = [1 1 1];   % Reset to white
        end

        % Compute the Fourier series
        syms x n;
        L = pi; % Half-period

        % Compute a0
        a0 = (1/L) * int(func, x, -L, L);

        % Compute an and bn
        an = (1/L) * int(func * cos(n*x), x, -L, L);
        bn = (1/L) * int(func * sin(n*x), x, -L, L);

        % Build the Fourier series
        fourierSeries = a0/2;
        for k = 1:numTerms
            fourierSeries = fourierSeries + subs(an, n, k)*cos(k*x) + subs(bn, n, k)*sin(k*x);
        end

        % Simplify the Fourier series expression
        fourierSeries = simplify(fourierSeries);

        % Display the Fourier series expression in the Result Display
        app.FourierSeriesTextArea.Value = {char(fourierSeries)};

        % Create function handles for plotting
        originalFunc = matlabFunction(func, 'Vars', x);
        fourierFunc = matlabFunction(fourierSeries, 'Vars', x);

        % Define the plotting range
        plotRange = [xStart, xEnd];

        % Plot the original function
        fplot(app.PlotAxes, originalFunc, plotRange, 'b', 'LineWidth', 2);
        hold(app.PlotAxes, 'on');

        % Plot the Fourier series approximation
        fplot(app.PlotAxes, fourierFunc, plotRange, 'r--', 'LineWidth', 2);

        hold(app.PlotAxes, 'off');

        % Enhance the plot
        grid(app.PlotAxes, 'on');
        xlabel(app.PlotAxes, 'x');
        ylabel(app.PlotAxes, 'f(x)');
        title(app.PlotAxes, 'Original Function vs Fourier Series Approximation');
        legend(app.PlotAxes, {'Original Function', 'Fourier Series Approximation'}, 'Location', 'best');

    catch ME
        % Handle and display errors using a dialog box
        uialert(app.UIFigure, ['Error: ', ME.message], 'Plotting Error');
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotAxes = uiaxes(app.UIFigure);
            title(app.PlotAxes, 'Original Function vs Fourier Series Approximation')
            xlabel(app.PlotAxes, 'x')
            ylabel(app.PlotAxes, 'f(x)')
            zlabel(app.PlotAxes, 'Z')
            app.EnterPeriodicSymbolicFunctionTextAreaLabel = uilabel(app.UIFigure);
            app.EnterPeriodicSymbolicFunctionTextAreaLabel.Text = 'Enter Periodic Symbolic Function:';
            app.SymbolicFunctionTextArea = uitextarea(app.UIFigure);
            app.SymbolicFunctionTextArea.Value = {'sin(x)'};
            app.NumberofFourierSeriesTermsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFourierSeriesTermsEditFieldLabel.Text = 'Number of Fourier Series Terms:';
            app.FourierTermsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FourierTermsEditField.Value = 10;
            app.PlotRangeStartEditFieldLabel = uilabel(app.UIFigure);
            app.PlotRangeStartEditFieldLabel.Text = 'Plot Range Start:';
            app.PlotStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotStartEditField.Value = -3.14;
            app.PlotRangeEndEditFieldLabel = uilabel(app.UIFigure);
            app.PlotRangeEndEditFieldLabel.Text = 'Plot Range End:';
            app.PlotEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotEndEditField.Value = 3.14;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierSeriesVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end