classdef FunctionPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlotFunctionButton         matlab.ui.control.Button
        XRangeEndEditField         matlab.ui.control.NumericEditField
        XRangeEndEditFieldLabel    matlab.ui.control.Label
        XRangeStartEditField       matlab.ui.control.NumericEditField
        XRangeStartEditFieldLabel  matlab.ui.control.Label
        EnterSymbolicExpressionTextArea  matlab.ui.control.TextArea
        ExpressionLabel            matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotFunctionButton
        function PlotFunctionButtonPushed(app, event)
       try
        % Retrieve the symbolic expression from the Text Area
        exprStr = strtrim(app.EnterSymbolicExpressionTextArea.Value);
        
        % Validate that the expression is not empty
        if isempty(exprStr)
            app.EnterSymbolicExpressionTextArea.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Please enter a symbolic expression.', 'Input Error');
            return;
        else
            app.EnterSymbolicExpressionTextArea.BackgroundColor = [1 1 1]; % Reset to white
        end

        % Convert the string to a symbolic expression using str2sym
        try
            expr = str2sym(exprStr);
        catch
            app.EnterSymbolicExpressionTextArea.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Invalid symbolic expression. Please check your input.', 'Expression Error');
            return;
        end

        % Extract variables from the symbolic expression
        vars = symvar(expr);
        if isempty(vars)
            uialert(app.UIFigure, 'No variables found in the expression.', 'Variable Error');
            return;
        end
        symVar = vars(1); % Use the first variable found

        % Retrieve and validate the plot range
        xStart = app.XRangeStartEditField.Value;
        xEnd = app.XRangeEndEditField.Value;

        if xStart >= xEnd
            app.XRangeStartEditField.BackgroundColor = [1 0.8 0.8]; % Light red
            app.XRangeEndEditField.BackgroundColor = [1 0.8 0.8];   % Light red
            uialert(app.UIFigure, 'X-Range Start must be less than X-Range End.', 'Range Error');
            return;
        else
            app.XRangeStartEditField.BackgroundColor = [1 1 1]; % Reset to white
            app.XRangeEndEditField.BackgroundColor = [1 1 1];   % Reset to white
        end

        % Create a function handle from the symbolic expression
        f = matlabFunction(expr, 'Vars', symVar);

        % Clear the axes before plotting
        cla(app.UIAxes);

        % Plot the function using fplot for better symbolic handling
        fplot(app.UIAxes, f, [xStart, xEnd], 'LineWidth', 2);

        % Enhance the plot
        grid(app.UIAxes, 'on');
        xlabel(app.UIAxes, char(symVar));
        ylabel(app.UIAxes, 'f(x)');
        title(app.UIAxes, ['Plot of ', char(expr)]);
        
        % Display the evaluated expression in OutputTextArea
        app.OutputTextArea.Value = {['f(', char(symVar), ') = ', char(expr)]};

    catch ME
        % Handle and display errors using a dialog box
        uialert(app.UIFigure, ['Error: ', ME.message], 'Plotting Error');
       end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Function Plot')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.ExpressionLabel = uilabel(app.UIFigure);
            app.ExpressionLabel.Text = 'Enter Symbolic Expression';
            app.EnterSymbolicExpressionTextArea = uitextarea(app.UIFigure);
            app.EnterSymbolicExpressionTextArea.Value = {'x^2 + 3*x + 2'};
            app.XRangeStartEditFieldLabel = uilabel(app.UIFigure);
            app.XRangeStartEditFieldLabel.Text = 'X Range Start';
            app.XRangeStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.XRangeStartEditField.Value = -10;
            app.XRangeEndEditFieldLabel = uilabel(app.UIFigure);
            app.XRangeEndEditFieldLabel.Text = 'X Range End';
            app.XRangeEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.XRangeEndEditField.Value = 10;
            app.PlotFunctionButton = uibutton(app.UIFigure, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Text = 'Plot Function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FunctionPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end