classdef SymbolicDiffIntApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotRangeEndEditField          matlab.ui.control.NumericEditField
        PlotRangeEndEditFieldLabel     matlab.ui.control.Label
        PlotRangeStartEditField        matlab.ui.control.NumericEditField
        PlotRangeStartEditFieldLabel   matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        OutputTextArea                 matlab.ui.control.TextArea
        ResultExpressionTextAreaLabel  matlab.ui.control.Label
        VariableDropDown               matlab.ui.control.DropDown
        VariableForOperationDropDownLabel  matlab.ui.control.Label
        OperationButtonGroup           matlab.ui.container.ButtonGroup
        IntegrateButton                matlab.ui.control.RadioButton
        DifferentiateButton            matlab.ui.control.RadioButton
        InputTextArea                  matlab.ui.control.TextArea
        InputExpressionTextAreaLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % 1. Set default plot ranges if not already set
            if isempty(app.PlotRangeStartEditField.Value)
                app.PlotRangeStartEditField.Value = -10;
            end
            if isempty(app.PlotRangeEndEditField.Value)
                app.PlotRangeEndEditField.Value = 10;
            end

            % 2. Populate the variable dropdown with additional variables
            app.VariableDropDown.Items = {'x', 'y', 'z', 't'};
            app.VariableDropDown.Value = 'x';

            % 3. Set default selection for operation
            app.DifferentiateButton.Value = true;
            app.IntegrateButton.Value = false;

            % 4. Set default input expression
            app.InputTextArea.Value = {'x^2 + 3*x + 2'}; % Example expression

            % 5. Make OutputTextArea read-only and set background color
            app.OutputTextArea.Editable = 'off'; % Make it read-only
            app.OutputTextArea.BackgroundColor = [0.95 0.95 0.95]; % Light gray background
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
    try
        % Retrieve the symbolic expression from the Input Text Area
        exprStr = strtrim(app.InputTextArea.Value);

        % Validate that the expression is not empty
        if isempty(exprStr)
            app.InputTextArea.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Please enter a symbolic expression.', 'Input Error');
            return;
        else
            app.InputTextArea.BackgroundColor = [1 1 1]; % Reset to white
        end

        % Retrieve the selected operation (Differentiate or Integrate)
        selectedOperation = app.OperationButtonGroup.SelectedObject.Text;

        % Retrieve the selected variable from the dropdown
        selectedVariable = app.VariableDropDown.Value;

        % Validate the selected variable
        if isempty(selectedVariable)
            app.VariableDropDown.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Please select a variable for the operation.', 'Input Error');
            return;
        else
            app.VariableDropDown.BackgroundColor = [1 1 1]; % Reset to white
        end

        % Convert the string to a symbolic expression using str2sym
        try
            expr = str2sym(exprStr);
        catch
            app.InputTextArea.BackgroundColor = [1 0.8 0.8]; % Light red
            uialert(app.UIFigure, 'Invalid symbolic expression. Please check your input.', 'Expression Error');
            return;
        end

        symVar = sym(selectedVariable);

        % Retrieve and validate the plot range
        xStart = app.PlotRangeStartEditField.Value;
        xEnd = app.PlotRangeEndEditField.Value;

        if xStart >= xEnd
            app.PlotRangeStartEditField.BackgroundColor = [1 0.8 0.8]; % Light red
            app.PlotRangeEndEditField.BackgroundColor = [1 0.8 0.8];   % Light red
            uialert(app.UIFigure, 'Plot Range Start must be less than Plot Range End.', 'Range Error');
            return;
        else
            app.PlotRangeStartEditField.BackgroundColor = [1 1 1]; % Reset to white
            app.PlotRangeEndEditField.BackgroundColor = [1 1 1];   % Reset to white
        end

        % Perform the selected symbolic operation
        switch selectedOperation
            case 'Differentiate'
                resultExpr = diff(expr, symVar);
            case 'Integrate'
                resultExpr = int(expr, symVar);
            otherwise
                uialert(app.UIFigure, 'Please select a valid operation.', 'Operation Error');
                return;
        end

        % Display the result in the Output Text Area
        app.OutputTextArea.Value = char(resultExpr);

        % Create function handles for plotting
        originalFunc = matlabFunction(expr, 'Vars', symVar);
        resultFunc = matlabFunction(resultExpr, 'Vars', symVar);

        % Define the range for plotting
        plotRange = [xStart, xEnd];

        % Clear previous plots
        cla(app.UIAxes);

        % Plot the original function
        fplot(app.UIAxes, originalFunc, plotRange, 'LineWidth', 2);
        hold(app.UIAxes, 'on');

        % Plot the differentiated/integrated function
        fplot(app.UIAxes, resultFunc, plotRange, 'LineWidth', 2);

        % Enhance the plot
        grid(app.UIAxes, 'on');
        xlabel(app.UIAxes, selectedVariable);
        ylabel(app.UIAxes, 'Function Value');
        title(app.UIAxes, ['Original and ', selectedOperation, ' Function']);
        legend(app.UIAxes, {'Original', [selectedOperation, 'd Function']}, 'Location', 'best');
        hold(app.UIAxes, 'off');

    catch ME
        % Handle and display errors using a dialog box
        uialert(app.UIFigure, ['Error: ', ME.message], 'Operation Error');
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Function Plot')
            xlabel(app.UIAxes, 'Variable')
            ylabel(app.UIAxes, 'Function Value')
            zlabel(app.UIAxes, 'Z')
            app.InputExpressionTextAreaLabel = uilabel(app.UIFigure);
            app.InputExpressionTextAreaLabel.Text = 'Input Expression:';
            app.InputTextArea = uitextarea(app.UIFigure);
            app.OperationButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationButtonGroup.Title = 'Select Operation';
            app.DifferentiateButton = uiradiobutton(app.OperationButtonGroup);
            app.DifferentiateButton.Text = 'Differentiate';
            app.DifferentiateButton.Value = true;
            app.IntegrateButton = uiradiobutton(app.OperationButtonGroup);
            app.IntegrateButton.Text = 'Integrate';
            app.VariableForOperationDropDownLabel = uilabel(app.UIFigure);
            app.VariableForOperationDropDownLabel.Text = 'Variable For Operation:';
            app.VariableDropDown = uidropdown(app.UIFigure);
            app.VariableDropDown.Items = {'x', 'y'};
            app.VariableDropDown.Value = 'x';
            app.ResultExpressionTextAreaLabel = uilabel(app.UIFigure);
            app.ResultExpressionTextAreaLabel.Text = 'Result Expression:';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotRangeStartEditFieldLabel = uilabel(app.UIFigure);
            app.PlotRangeStartEditFieldLabel.Text = 'Plot Range Start:';
            app.PlotRangeStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotRangeStartEditField.Value = -10;
            app.PlotRangeEndEditFieldLabel = uilabel(app.UIFigure);
            app.PlotRangeEndEditFieldLabel.Text = 'Plot Range End:';
            app.PlotRangeEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotRangeEndEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SymbolicDiffIntApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end